classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        KlowvaluequickiterationLabel  matlab.ui.control.Label
        KSlider                       matlab.ui.control.Slider
        OutputFilePathButton          matlab.ui.control.Button
        ByVedantNaikLabel             matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        CalculateButton               matlab.ui.control.Button
        NoneLabel                     matlab.ui.control.Label
        FileSelectedLabel             matlab.ui.control.Label
        SelectFileButton              matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
   properties (Access = private)
    Data table % Table to store the data
    result_folder = ''; % Stores the path of the result folder
    selected_output_dir = ''; % Stores the selected output directory
end

    
    methods (Access = private)
        
        function result_dir_o(app, output_dir)
            % Create a folder named "results" if it doesn't exist
            if ~exist(fullfile(output_dir, 'results'), 'dir')
                mkdir(fullfile(output_dir, 'results'));
            end
            
            % Get the current date and time
            current_time = datestr(now, 'yyyy_mm_dd_HH_MM_SS');
            
            % Create a subfolder in "results" with the current date and time
            app.result_folder = fullfile(output_dir, 'results', current_time);
            mkdir(app.result_folder);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function result_dir(app)
          
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
           % Set the file filter for Excel files
filter = {'*.xlsx;*.xls', 'Excel Files (*.xlsx, *.xls)'};

% Open the file browser and get the selected file path
[filename, pathname] = uigetfile(filter, 'Select an Excel file');

% Check if the user selected a file
if isequal(filename, 0)
    % No file was selected, display a message
    disp('No file was selected');
else
    % File was selected, display the selected file path
    disp(['Selected file: ', fullfile(pathname, filename)]);
    app.NoneLabel.Text = filename;        
    % Perform the desired actions with the selected Excel file
    app.Data = readtable(fullfile(pathname, filename));
    % Make the Calculate button visible
    app.OutputFilePathButton.Enable = 'on';
    app.CalculateButton.Enable = 'on';
    app.KSlider.Enable ='on';
end

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
             % Call the result_dir function with the selected output directory
            if ~isempty(app.selected_output_dir)
                result_dir_o(app, app.selected_output_dir);
            else
                result_dir_o(app, pwd); % Use current working directory if the output directory is not selected
            end
            progress_bar = waitbar(0, 'Processing data... Please wait...');
            app.KSlider.Enable ='off';
            app.OutputFilePathButton.Enable = 'off';
            app.CalculateButton.Enable = 'off';
            % Remove rows where the electric beam current is negative
            app.RightPanel.Enable ="on";
            data = app.Data;
            % Sort the data by timestamp
            data = sortrows(data, 1);
            mask = data{:, 3} >= 0;
            data = data(mask, :);
            
            mask = data{:,2} <= 50 & data{:,2}>=0; % mm
            data = data(mask, :);
            
            energy_loss = data{:,7}-data{:,8};
            
            mask = energy_loss>0.2 & energy_loss<0.9;
            data = data(mask,:);
            
            % Extract the electric beam current and particle/beam charge columns
            electric_current = data{:,3}.*1e-6; % in amperes
            particle_beam_charge = data{:,4}; % in number of e
            e = 1.60217662e-19; % Coulombs
            
            % Cluster r_para values using k-means
            r_para = data{:, 2}; % mm
            
            silhouette_scores = [];
            K = round(app.KSlider.Value);
            
            % Convert r_para into an n-by-1 matrix
            r_para_matrix = r_para(:);
            
            for k = 2:K
                [idx, ~] = kmeans(r_para_matrix, k);
                silhouette_scores(k) = mean(silhouette(r_para_matrix, idx));
            end
            
            % Find optimal number of clusters
            [~, k_opt] = max(silhouette_scores);
            fprintf('Optimal number of clusters: %d\n', k_opt);
            
            % Perform final k-means clustering with optimal number of clusters
            [idx, centers] = kmeans(r_para_matrix, k_opt);
            
            % Determine inner and outer radii for each annulus
            inner_radii = zeros(k_opt, 1);
            outer_radii = zeros(k_opt, 1);
            for i = 1:k_opt
                mask = (idx == i);
                inner_radii(i) = min(r_para(mask));
                outer_radii(i) = max(r_para(mask));
            end
            % Plot silhouette scores
            % figure; (Remove this line)
            plot(app.UIAxes, 2:K, silhouette_scores(2:end), '-o');
            xlabel(app.UIAxes, 'Number of clusters');
            ylabel(app.UIAxes, 'Silhouette score');
            title(app.UIAxes, 'Silhouette method');
            grid(app.UIAxes, 'on');
            inner_radii = sort(inner_radii);
            outer_radii = sort(outer_radii);


            e = 1.602176634e-19; % Elementary charge, C
            
            for i = 1:k_opt
                 % Update progress/wait bar
                progress = i / k_opt;
                waitbar(progress, progress_bar, sprintf('Progress: %3.1f %%', progress * 100));
                % Filter data based on inner and outer radii             
                mask = (r_para >= inner_radii(i)) & (r_para <= outer_radii(i));
                annulus_data{i} = data(mask, :);
                charge = annulus_data{i}{:, 4};
                G = annulus_data{i}{:, 7};
                H = annulus_data{i}{:, 8};
                energy_loss = (G - H);
                
                % Calculate the particle per second for the current annulus region
                particle_per_second = electric_current(mask)./(e .* charge); % particles/s
            
                % Calculate the time differences between each time stamp in seconds
                time_diff = seconds(diff(annulus_data{i}{:, 1}));
                time_diff(isnan(time_diff)) = 0;
            
                % Pad time_diff array with an additional zero at the beginning
                time_diff = [0; time_diff];
            
                % Calculate the area of the current annulus region
                area = pi * (outer_radii(i)^2 - inner_radii(i)^2)/1e6;
            
                % Calculate the cumulative fluence for the current annulus region
                fluence_rate = particle_per_second .* time_diff;
                fluence{i} = cumsum(fluence_rate) / area;
            
                % Fit a 3rd order polynomial
                p = polyfit(fluence{i}, energy_loss, 13);
                trendline = polyval(p, fluence{i});
                
                % Calculate the standard deviation
                std_dev = std(energy_loss);
                
                % Create a new figure
                fig = figure;
                %scatter(fluence{i}, energy_loss);
                hold on;
                
                % Plot trendline
                plot(fluence{i}, trendline, 'r', 'LineWidth', 2);
                
                % Plot shaded region (using standard deviation)
                fill([fluence{i}; flipud(fluence{i})], [trendline + std_dev; flipud(trendline - std_dev)], ...
                     'r', 'FaceAlpha', 0.1, 'EdgeColor', 'none');
                
                xlabel('Accumulated fluence (particles/m^2)');
                ylabel('Energy loss (MeV/n)');
                title(sprintf('Annulus Region %d %f-%f', i, inner_radii(i), outer_radii(i)));
                
                hold off;
                 % Close the figure to save memory
                fig=gcf;
                
               % Save the chart as a JPEG image
                chart_filename = sprintf('Annulus_Region_%d_%f-%f.jpg', i, inner_radii(i), outer_radii(i));
                chart_filepath = fullfile(app.result_folder, chart_filename);
                 saveas(fig, sprintf('%s/annulus_region_%d.jpg', app.result_folder, i));
                                
                close(fig);
                % Create a table with the data to be saved in the Excel file
                % Create a table with the data to be saved in the Excel file
                excel_data = table(annulus_data{i}{:, 1}, time_diff, fluence{i}, energy_loss, 'VariableNames', {'Timestamp', 'Time_Diff', 'Accumulated_Fluence', 'Energy_Loss'});
                % Save the data as an Excel file
                excel_filename = sprintf('Annulus_Region_%d_%f-%f.xlsx', i, inner_radii(i), outer_radii(i));
                excel_filepath = fullfile(app.result_folder, excel_filename);
                writetable(excel_data, excel_filepath);

            end
             close(progress_bar);

        end

        % Button pushed function: OutputFilePathButton
        function OutputFilePathButtonPushed(app, event)
           % Open the folder browser and get the selected folder path
            selected_dir = uigetdir();
            
            % Check if the user selected a folder
            if isequal(selected_dir, 0)
                % No folder was selected, display a message
                disp('No folder was selected');
            else
                % Folder was selected, store the selected folder path and display it
                app.selected_output_dir = selected_dir;
                disp(['Selected folder: ', app.selected_output_dir]);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {436, 436};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {194, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectFileButton = uibutton(app.LeftPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'SelectFile';
            app.FileSelectedLabel = uilabel(app.LeftPanel);
            app.FileSelectedLabel.Text = 'File Selected:';
            app.NoneLabel = uilabel(app.LeftPanel);
            app.NoneLabel.Text = 'None';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cfoil_logo.png');
            app.ByVedantNaikLabel = uilabel(app.LeftPanel);
            app.ByVedantNaikLabel.Text = 'By. Vedant Naik ';
            app.OutputFilePathButton = uibutton(app.LeftPanel, 'push');
            app.OutputFilePathButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFilePathButtonPushed, true);
            app.OutputFilePathButton.Enable = 'off';
            app.OutputFilePathButton.Text = 'Output File Path';
            app.KSlider = uislider(app.LeftPanel);
            app.KSlider.Limits = [3 13];
            app.KSlider.MajorTicks = [3 4 5 6 7 8 9 10 11 12 13];
            app.KSlider.MinorTicks = [];
            app.KSlider.Enable = 'off';
            app.KSlider.Value = 5;
            app.KlowvaluequickiterationLabel = uilabel(app.LeftPanel);
            app.KlowvaluequickiterationLabel.Text = 'K- low value=quick iteration';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Enable = 'off';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Silhouette method')
            xlabel(app.UIAxes, 'Number of clusters')
            ylabel(app.UIAxes, 'Silhouette score')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @result_dir)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end